classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        StatusTextArea       matlab.ui.control.TextArea
        StatusTextAreaLabel  matlab.ui.control.Label
        ConnectButton        matlab.ui.control.Button
    end

    
properties (Access = private)
    MQTTClient % MQTT client
    BrokerAddress = 'mqtt://iotsmarthouse.go.ro' % Default broker address
    Port = 1883 % Default port
    MQTTUser = 'baiaMare' % Default username
    MQTTPassword = 'Ares' % Default password
end

methods (Access = private)
    function openControlWindow(app)
        controlFig = uifigure('Name', 'Catapult Control Panel', 'Position', [100, 100, 600, 400]);
        
        ax = uiaxes(controlFig, 'Position', [50, 100, 540, 300]);
        view(ax, 3);
        xlabel(ax, 'X');
        ylabel(ax, 'Y');
        zlabel(ax, 'Z');
        grid(ax, 'on');
        
        fv = stlread('catapulta.stl');
        
        vertices = fv.Points;
        vertices = vertices - mean(vertices);
        vertices(:, 3) = vertices(:, 3) + 250;
        
        patch(ax, 'Faces', fv.ConnectivityList, 'Vertices', vertices, ...
              'FaceColor', [0.8 0.8 1.0], 'EdgeColor', 'none', ...
              'FaceLighting', 'gouraud', 'AmbientStrength', 0.15);
        camlight(ax, 'headlight');
        material(ax, 'dull');
        axis(ax, 'image');
        view(ax, [135 35]);
        
        uilabel(controlFig, 'Position', [100, 70, 120, 20], 'Text', 'X Coordinate:');
        xCoordInput = uieditfield(controlFig, 'numeric', 'Position', [100, 50, 120, 20]);
        
        uilabel(controlFig, 'Position', [250, 70, 120, 20], 'Text', 'Y Coordinate:');
        yCoordInput = uieditfield(controlFig, 'numeric', 'Position', [250, 50, 120, 20]);
        
        launchButton = uibutton(controlFig, 'Text', 'Launch', 'Position', [400, 50, 120, 30], ...
                                'ButtonPushedFcn', @(btn, event) launchProjectile(xCoordInput.Value, yCoordInput.Value));
        
        v0xLabel = uilabel(controlFig, 'Position', [100, 20, 300, 20], 'Text', 'v0x: ');
        v0yLabel = uilabel(controlFig, 'Position', [100, 0, 300, 20], 'Text', 'v0y: ');
        v0zLabel = uilabel(controlFig, 'Position', [250, 20, 300, 20], 'Text', 'v0z: ');
        timeOfFlightLabel = uilabel(controlFig, 'Position', [250, 0, 300, 20], 'Text', 'Time of Flight: ');
        torsionLabel = uilabel(controlFig, 'Position', [400, 20, 300, 20], 'Text', 'Torsion: ');
        
        app.MQTTClient = mqttclient(app.BrokerAddress, ...
            'Port', app.Port, ...
            'Username', app.MQTTUser, ...
            'Password', app.MQTTPassword);
        
        inTopic = 'inTopic';
        
        function launchProjectile(xCoord, yCoord)
            disp(['Launching projectile to X: ', num2str(xCoord), ', Y: ', num2str(yCoord)]);
            
            publishMessage(app.MQTTClient, inTopic, ['X:', num2str(xCoord), ' Y:', num2str(yCoord)]);
            
            g = 9.81;
            angleXY = atan2(yCoord, xCoord);
            angleXZ = 30;
            h0 = 0.3;
            v0 = sqrt(g * sqrt(xCoord^2 + yCoord^2) / sin(2 * angleXY));
            
            angleXZRad = deg2rad(angleXZ);
            
            v0x = v0 * cos(angleXY) * cos(angleXZRad);
            v0y = v0 * sin(angleXY);
            v0z = v0 * cos(angleXY) * sin(angleXZRad);
            
            v0_total = sqrt(v0x^2 + v0y^2 + v0z^2);
            
            tMax = (v0y + sqrt(v0y^2 + 2 * g * h0)) / g;
            t = linspace(0, tMax, 100);
            
            xPos = v0x * t;
            yPos = v0y * t - 0.5 * g * t.^2;
            zPos = h0 + v0z * t - 0.5 * g * t.^2;
            
            distance = sqrt(xPos(end)^2 + yPos(end)^2);
            
            v0xLabel.Text = ['v0x: ', num2str(v0x)];
            v0yLabel.Text = ['v0y: ', num2str(v0y)];
            v0zLabel.Text = ['v0z: ', num2str(v0z)];
            timeOfFlightLabel.Text = ['Time of Flight: ', num2str(tMax)];
            torsionLabel.Text = ['V0: ', num2str(v0)];
            
            plotHandle = plot3(ax, xPos(1), yPos(1), zPos(1), 'r', 'LineWidth', 2);
            for i = 2:length(t)
                plotHandle.XData = xPos(1:i);
                plotHandle.YData = yPos(1:i);
                plotHandle.ZData = zPos(1:i);
                drawnow;
            end
        end

        function publishMessage(client, topic, message)
            if isvalid(client)
                write(client, topic, message);
                disp(['Published to ', topic, ': ', message]);
            else
                disp('MQTT client is not valid.');
            end
        end
    end
    
    function results = opnAdditionalWIndow(app)
        additionalFig = uifigure('Name', 'Additional Window', 'Position', [200, 200, 400, 300]);
        
        button1 = uibutton(additionalFig, 'Text', 'Button 1', 'Position', [50, 200, 100, 50], ...
                           'ButtonPushedFcn', @(btn, event) disp('Button 1 pressed'));
        button2 = uibutton(additionalFig, 'Text', 'Button 2', 'Position', [150, 200, 100, 50], ...
                           'ButtonPushedFcn', @(btn, event) disp('Button 2 pressed'));
        button3 = uibutton(additionalFig, 'Text', 'Button 3', 'Position', [250, 200, 100, 50], ...
                           'ButtonPushedFcn', @(btn, event) disp('Button 3 pressed'));
    end
end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            try
                app.MQTTClient = mqttclient(app.BrokerAddress, ...
                                Port=app.Port, ...
                                Username=app.MQTTUser, ...
                                Password=app.MQTTPassword);
                            
            % Update the status area
                app.StatusTextArea.Value = "Connected to " + app.BrokerAddress;
                openControlWindow(app);
                %openSimulator(app);
            catch ME
            % Update status area with error message
                app.StatusTextArea.Value = "Failed to connect: " + ME.message;
            end

        end

        % Value changed function: StatusTextArea
        function MessageTextArea(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.StatusTextAreaLabel = uilabel(app.UIFigure);
            app.StatusTextAreaLabel.Text = 'Status';
            app.StatusTextArea = uitextarea(app.UIFigure);
            app.StatusTextArea.ValueChangedFcn = createCallbackFcn(app, @MessageTextArea, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end